classdef MATLAB_HumidityProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlantWaterSystemUIFigure     matlab.ui.Figure
        PORTPanel                    matlab.ui.container.Panel
        PortEditFieldLabel           matlab.ui.control.Label
        PortEditField                matlab.ui.control.EditField
        TYPEPanel                    matlab.ui.container.Panel
        ThresholdColorDropDownLabel  matlab.ui.control.Label
        ThresholdColorDropDown       matlab.ui.control.DropDown
        HumidityColorDropDown        matlab.ui.control.DropDown
        HumidityColorDropDownLabel   matlab.ui.control.Label
        ThresholdDropDownLabel       matlab.ui.control.Label
        ThresholdDropDown            matlab.ui.control.DropDown
        ThresholdWidthDropDown       matlab.ui.control.DropDown
        ThresholdWidthDropDownLabel  matlab.ui.control.Label
        HumidityWidthDropDownLabel   matlab.ui.control.Label
        HumidityWidthDropDown        matlab.ui.control.DropDown
        FileTypeDropDownLabel        matlab.ui.control.Label
        FileTypeDropDown             matlab.ui.control.DropDown
        HumMarkerDropDownLabel       matlab.ui.control.Label
        HumMarkerDropDown            matlab.ui.control.DropDown
        CONTROLPanel                 matlab.ui.container.Panel
        PLOTButton                   matlab.ui.control.Button
        SAVEButton                   matlab.ui.control.Button
        PRINTButton                  matlab.ui.control.Button
        EXITButton                   matlab.ui.control.Button
        EXCELButton                  matlab.ui.control.Button
        CONTROL2Panel                matlab.ui.container.Panel
        TimeValue                    matlab.ui.control.NumericEditField
        tEditFieldLabel              matlab.ui.control.Label
        GridOnOffSwitchLabel         matlab.ui.control.Label
        GridOnOffSwitch              matlab.ui.control.Switch
        DataLabel                    matlab.ui.control.Label
        DataEditField                matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
    end

    
properties (Access = private)
        arduinoObj % Arduino object
        isReading = false; % Veri okuma durumunu yöneten flag
        thresholdValue = 0;% Varsayılan eşik değeri
        sensorLine % Sensör verisi için animasyonlu çizgi
        thresholdLine % Treshold için animasyonlu çizgi
        startTime % Çizimi başlatma zamanı
        dataTimer % Veri toplama için zamanlayıcı
        dataBuffer = []; % Veriyi depolamak için tampon
        bufferSize = 10 % Son 100 veriyi depolarız
        duration= 10; %süre
        n = 1;
end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.arduinoObj = arduino(); % Arduino bağlantısını başlatır
            app.PortEditField.Value = app.arduinoObj.Port; % EditField ile Arduino'nun bağlı olduğu portu gösterir.
            app.dataBuffer = zeros(app.bufferSize, 1); % Belirli bir boyutta sıfırlar içeren bir sütun vektörü oluşturur.
             
            if isa(app.arduinoObj, 'arduino') % Arduino bağlantısının geçerli olup olmadığını kontrol eder.
                configurePin(app.arduinoObj, 'D13', 'DigitalOutput'); %D13 numaralı pin, dijital bir çıkış olarak yapılandırılır.
            else
                error('Failed to initialize Arduino connection.');
            end

            app.sensorLine = animatedline(app.UIAxes, 'Color', 'b', 'Marker', '_'); % AXIS üzerinde mavi renkte bir animasyonlu çizgi oluşturur. Bu çizgi, sensör verilerini temsil eder
            app.thresholdLine = animatedline(app.UIAxes, 'Color', 'r'); % AXIS üzerinde kırmızı renkte bir animasyonlu çizgi oluşturur. Bu çizgi, treshold verilerini temsil eder

            app.dataTimer = timer('ExecutionMode', 'fixedRate', 'Period', 1, ...
                          'TimerFcn', @(~,~)app.collectData); % Veri toplama işlemi için bir zamanlayıcı oluşturduk. Her bir saniyede bir çalışacak ve app.collectData fonksiyonunu çağıracaktır.
            
            start(app.dataTimer); % Oluşturulan zamanlayıcıyı başlatır, böylece belirli aralıklarla veri toplama işlemi devam eder
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            app.startTime = datetime('now'); % Zamanı başlangıç ​​zamanına ayarlar ve sensör verilerinin ne kadar süre boyunca toplandığını izlemek için kullanılır
                if ~isa(app.arduinoObj, 'arduino')
                    app.arduinoObj = arduino();
                end
            % Sensör sabitlerİ
            sensorPin = 'A0'; % Sensörün bağlı olduğu pin 
            voltageRange = [0, 5]; % Sensörün ölçüm gerilim aralığı
            humidityRange = [100, 0]; % Sensörün ölçtüğü nem aralığı
            if ~app.isReading %eğer veri okuma işlemi devam etmiyorsa
                app.isReading = true; % Okuma işlemine başla
                while app.isReading
                    analogValue = readVoltage(app.arduinoObj, sensorPin); % Analog değeri oku 
                    humidityPercentage = interp1(voltageRange, humidityRange, analogValue); % Analog değeri nem yüzdesine dönüştür
                    
                    % humidityPercentage geçerliyse
                    if ~isnumeric(humidityPercentage) || isnan(humidityPercentage) || humidityPercentage < 0 || humidityPercentage > 100
                        continue; % Geçersiz değerse bu iterasyonu atla
                    end
                    currentTime = datetime('now'); % Zamanı güncelle
                    elapsedSeconds = seconds(currentTime - app.startTime); % Saniye cinsinden geçen zamanı hesapla
                    addpoints(app.sensorLine, elapsedSeconds, humidityPercentage); % Grafik çizgisini güncelle ve eşik çizgisine yeni veri noktası ekler
                    addpoints(app.thresholdLine, elapsedSeconds, app.thresholdValue);
                    ahm = [elapsedSeconds, humidityPercentage];
                    app.dataBuffer = [app.dataBuffer; humidityPercentage];
                    % Eğer nem değeri eşik değeri aşıyorsa
                    if humidityPercentage > app.thresholdValue
                        writeDigitalPin(app.arduinoObj, 'D13', 0); % D13 pinini aktif hale getir
                    else
                        writeDigitalPin(app.arduinoObj, 'D13', 1); % D13 pinini pasif hale getir
                    end
                    
                    drawnow;
                end
            end


        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: TimeValue
        function TimeValueValueChanged(app, event)

            app.duration = app.TimeValue.Value; % Edit alanındaki değeri alır ve duration adlı bir değişkene atar
            app.UIAxes.XLim = [0 app.duration]; % x-ekseni sınırlarını belirler. Bu durumda, x-ekseninin minimum değeri 0, maksimum değeri ise edit alanındaki değer olan duration olacaktır
            app.UIAxes.XTick = 0:app.duration/10:app.duration; %  x-eksenindeki işaretçilerin yerleşimini belirler. Bu durumda, 0'dan başlayarak duration değerine kadar olan aralığı duration/10 adım büyüklüğünde böler ve bu değerleri x-eksenindeki işaretçi konumları olarak belirler.

            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: ThresholdDropDown
        function ThresholdDropDownValueChanged(app, event)
            app.thresholdValue = str2double(app.ThresholdDropDown.Value);
            
        end

        % Value changed function: ThresholdColorDropDown
        function ThresholdColorDropDownValueChanged(app, event)
            value = app.ThresholdColorDropDown.Value;
             switch value
                 case 'Red'
                     color = 'r';
                 case 'Green'
                     color = 'g';
                 case 'Magenta'
                     color = 'm';
                 case 'Black'
                     color = 'k';
                 case 'Yellow'
                     color = 'y';
                 case 'Blue'
                     color = 'b';    
            otherwise
                color = 'b'; % Default color
        end

        app.thresholdLine.Color = color; % Set the color of the threshold line
    

        end

        % Value changed function: ThresholdWidthDropDown
        function ThresholdWidthDropDownValueChanged(app, event)
            selectedLineWidth = app.ThresholdWidthDropDown.Value;
             switch selectedLineWidth
            case 'Thin'
                newLineWidth = 0.5;
            case 'Middle'
                newLineWidth = 1;
            case 'Thick'
                newLineWidth = 1.5;
            otherwise
                newLineWidth = 0.5; % Default
        end

        app.thresholdLine.LineWidth = newLineWidth; % Treshold grafiği
    
        end

        % Value changed function: HumidityWidthDropDown
        function HumidityWidthDropDownValueChanged(app, event)
           
            selectedLineWidth = app.HumidityWidthDropDown.Value;
        switch selectedLineWidth
            case 'Thin'
                newLineWidth = 0.5;
            case 'Middle'
                newLineWidth = 1;
            case 'Thick'
                newLineWidth = 1.5;
            otherwise
                newLineWidth = 0.5; % Default
        end

        app.sensorLine.LineWidth = newLineWidth; % Nem grafiği
        
        end

        % Value changed function: HumidityColorDropDown
        function HumidityColorDropDownValueChanged(app, event)
            value1 = app.HumidityColorDropDown.Value;
       switch value1
                 case 'Red'
                     color1 = 'r';
                 case 'Green'
                     color1 = 'g';
                 case 'Magenta'
                     color1 = 'm';
                 case 'Black'
                     color1 = 'k';
                 case 'Yellow'
                     color1 = 'y';
                 case 'Blue'
                     color1 = 'b';    
            otherwise
                color1 = 'b'; % Default color
       end
            app.sensorLine.Color = color1;
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
           
            
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            fileType = app.FileTypeDropDown.Value; % DropDown öğesinden seçilen dosya türünü alır
            fileName = fullfile(getenv('USERPROFILE'), 'Desktop', 'humidity'); % Dosyanın kaydedileceği konumu ve dosya adını belirler. Bu örnekte, dosya adı "humidity" olacak şekilde masaüstüne kaydedilir.
    
            switch fileType
                case 'Jpeg'
                    exportgraphics(app.UIAxes, [fileName '.jpeg'], 'Resolution', 300);
                case 'Jpg'
                    exportgraphics(app.UIAxes, [fileName '.jpg'], 'Resolution', 300);
                case 'Png'
                    exportgraphics(app.UIAxes, [fileName '.png'], 'Resolution', 300);
                case 'Fig'
                    figHandle = ancestor(app.UIAxes, 'figure');  
                        if ishandle(figHandle) 
                            savefig(figHandle, [fileName '.fig']); % figür dosyasını kaydetmek için savefig fonksiyonu kullanılır
                        else
                            error('Invalid figure handle.');
                        end
                case 'Mat'
                    dataToSave = get(app.UIAxes, 'UserData');  
                    save([fileName '.mat'], 'dataToSave');


            end
        end

        % Button pushed function: PRINTButton
        function PRINTButtonPushed(app, event)
        
        drawnow; % update
        pause(0.1); % Grafik render edildikten sonra kısa bir bekleme süresi ekler ve grafik oluşturulduktan sonra ekranın güncellenmesine olanak tanır
        f = getframe(app.UIAxes); % frame olarak alır
        toShow = figure('Visible', 'off'); % Görüntünün gösterileceği yeni bir figür oluşturur ancak başlangıçta görünürlüğü kapalı olarak ayarlar
        warning('off', 'images:initSize:adjustingMag');
        imshow(f.cdata);
        warning('on', 'images:initSize:adjustingMag'); 
        set(toShow, 'PaperOrientation', 'landscape', ...
            'PaperUnits', 'normalized', 'PaperPosition', [0 0 1 1]); %Yazdırma özelliklerini ayarlar kağıt oryantasyonunu, kağıt birimini ve kağıt pozisyonunu belirler
        printdlg(toShow); % yazdırma arayüzünü açar
        close(toShow);

 
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.GridOnOffSwitch.Value;
        end

        % Value changed function: GridOnOffSwitch
        function GridOnOffSwitchValueChanged(app, event)
            value = app.GridOnOffSwitch.Value;
                if value  == "On"
                    grid(app.UIAxes, 'on');
                else
                    grid(app.UIAxes, 'off');
                end
        end

        % Button pushed function: EXCELButton
        function EXCELButtonPushed(app, event)
             excelFileName = 'SensorData.xlsx'; % Excel dosyasının adını belirler.
             system(['start excel "' excelFileName '"']); % Excel dosyasının adını belirler.
             writematrix(app.dataBuffer, excelFileName); % dataBuffer adlı matrisi belirtilen Excel dosyasına yazar ve writematrix fonksiyonu, MATLAB matrisini belirtilen dosyaya yazmak için kullanılır
        end

        % Value changed function: DataEditField
        function DataEditFieldValueChanged(app, event)
            app.bufferSize = app.DataEditField.Value; % matris boyutunu belirler
        end

        % Value changed function: HumMarkerDropDown
        function HumMarkerDropDownValueChanged(app, event)
            value = app.HumMarkerDropDown.Value;
              
            switch value
                 case '+'
                    marker = '+';
                 case 's'
                    marker = 's';
                 case 'o'
                    marker = 'o';
               
     
            otherwise
                marker = '_'; 
            end
        app.sensorLine.Marker = marker;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlantWaterSystemUIFigure = uifigure('Visible', 'off');
            app.PlantWaterSystemUIFigure.Name = 'PlantWaterSystem';
            app.PORTPanel = uipanel(app.PlantWaterSystemUIFigure);
            app.PORTPanel.Title = 'PORT';
            app.PortEditFieldLabel = uilabel(app.PORTPanel);
            app.PortEditFieldLabel.Text = 'Port : ';
            app.PortEditField = uieditfield(app.PORTPanel, 'text');
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Editable = 'off';
            app.TYPEPanel = uipanel(app.PlantWaterSystemUIFigure);
            app.TYPEPanel.Title = 'TYPE';
            app.ThresholdColorDropDownLabel = uilabel(app.TYPEPanel);
            app.ThresholdColorDropDownLabel.Text = 'Threshold Color';
            app.ThresholdColorDropDown = uidropdown(app.TYPEPanel);
            app.ThresholdColorDropDown.Items = {'Select', 'Red', 'Green', 'Magenta', 'Black', 'Yellow', 'Blue', ''};
            app.ThresholdColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdColorDropDownValueChanged, true);
            app.ThresholdColorDropDown.Value = 'Select';
            app.HumidityColorDropDown = uidropdown(app.TYPEPanel);
            app.HumidityColorDropDown.Items = {'Select', 'Red', 'Green', 'Magenta', 'Black', 'Yellow', 'Blue'};
            app.HumidityColorDropDown.ValueChangedFcn = createCallbackFcn(app, @HumidityColorDropDownValueChanged, true);
            app.HumidityColorDropDown.Value = 'Select';
            app.HumidityColorDropDownLabel = uilabel(app.TYPEPanel);
            app.HumidityColorDropDownLabel.Text = 'Humidity Color';
            app.ThresholdDropDownLabel = uilabel(app.TYPEPanel);
            app.ThresholdDropDownLabel.Text = 'Threshold';
            app.ThresholdDropDown = uidropdown(app.TYPEPanel);
            app.ThresholdDropDown.Items = {'Select', '10', '20', '30', '40', '50', '60', '70', '80', '90'};
            app.ThresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdDropDownValueChanged, true);
            app.ThresholdDropDown.Value = 'Select';
            app.ThresholdWidthDropDown = uidropdown(app.TYPEPanel);
            app.ThresholdWidthDropDown.Items = {'Select', 'Thin', 'Middle', 'Thick'};
            app.ThresholdWidthDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdWidthDropDownValueChanged, true);
            app.ThresholdWidthDropDown.Value = 'Select';
            app.ThresholdWidthDropDownLabel = uilabel(app.TYPEPanel);
            app.ThresholdWidthDropDownLabel.Text = 'Threshold Width ';
            app.HumidityWidthDropDownLabel = uilabel(app.TYPEPanel);
            app.HumidityWidthDropDownLabel.Text = 'Humidity Width ';
            app.HumidityWidthDropDown = uidropdown(app.TYPEPanel);
            app.HumidityWidthDropDown.Items = {'Select', 'Thin', 'Middle', 'Thick'};
            app.HumidityWidthDropDown.ValueChangedFcn = createCallbackFcn(app, @HumidityWidthDropDownValueChanged, true);
            app.HumidityWidthDropDown.Value = 'Select';
            app.FileTypeDropDownLabel = uilabel(app.TYPEPanel);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.TYPEPanel);
            app.FileTypeDropDown.Items = {'Select', 'Jpeg', 'Jpg', 'Png', 'Fig', 'Mat'};
            app.FileTypeDropDown.Value = 'Select';
            app.HumMarkerDropDownLabel = uilabel(app.TYPEPanel);
            app.HumMarkerDropDownLabel.Text = 'Hum Marker';
            app.HumMarkerDropDown = uidropdown(app.TYPEPanel);
            app.HumMarkerDropDown.Items = {'Select', '+', 's', 'o', '.', ''};
            app.HumMarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @HumMarkerDropDownValueChanged, true);
            app.HumMarkerDropDown.Value = 'Select';
            app.CONTROLPanel = uipanel(app.PlantWaterSystemUIFigure);
            app.CONTROLPanel.Title = 'CONTROL';
            app.PLOTButton = uibutton(app.CONTROLPanel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.SAVEButton = uibutton(app.CONTROLPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.PRINTButton = uibutton(app.CONTROLPanel, 'push');
            app.PRINTButton.ButtonPushedFcn = createCallbackFcn(app, @PRINTButtonPushed, true);
            app.PRINTButton.Text = 'PRINT';
            app.EXITButton = uibutton(app.CONTROLPanel, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = {'EXIT'; ''};
            app.EXCELButton = uibutton(app.CONTROLPanel, 'push');
            app.EXCELButton.ButtonPushedFcn = createCallbackFcn(app, @EXCELButtonPushed, true);
            app.EXCELButton.Text = 'EXCEL';
            app.CONTROL2Panel = uipanel(app.PlantWaterSystemUIFigure);
            app.CONTROL2Panel.Title = 'CONTROL2';
            app.TimeValue = uieditfield(app.CONTROL2Panel, 'numeric');
            app.TimeValue.ValueChangedFcn = createCallbackFcn(app, @TimeValueValueChanged, true);
            app.TimeValue.Value = 10;
            app.tEditFieldLabel = uilabel(app.CONTROL2Panel);
            app.tEditFieldLabel.Text = 't: ';
            app.GridOnOffSwitchLabel = uilabel(app.CONTROL2Panel);
            app.GridOnOffSwitchLabel.Text = 'Grid On/Off';
            app.GridOnOffSwitch = uiswitch(app.CONTROL2Panel, 'slider');
            app.GridOnOffSwitch.ValueChangedFcn = createCallbackFcn(app, @GridOnOffSwitchValueChanged, true);
            app.DataLabel = uilabel(app.CONTROL2Panel);
            app.DataLabel.Text = 'Data :';
            app.DataEditField = uieditfield(app.CONTROL2Panel, 'numeric');
            app.DataEditField.ValueChangedFcn = createCallbackFcn(app, @DataEditFieldValueChanged, true);
            app.DataEditField.Value = 10;
            app.UIAxes = uiaxes(app.PlantWaterSystemUIFigure);
            title(app.UIAxes, 'Plant Water System')
            xlabel(app.UIAxes, 'Time(seconds)')
            ylabel(app.UIAxes, 'Humidty/Treshold')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.PlantWaterSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_HumidityProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlantWaterSystemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlantWaterSystemUIFigure)
        end
    end
end