classdef homework2_usingslidergaugeswitch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UsingSliderGaugeandSwitchUIFigure  matlab.ui.Figure
        ParametersPanel     matlab.ui.container.Panel
        Label               matlab.ui.control.Label
        Gauge               matlab.ui.control.SemicircularGauge
        ASliderLabel        matlab.ui.control.Label
        ASlider             matlab.ui.control.Slider
        EditField           matlab.ui.control.NumericEditField
        EditField_2         matlab.ui.control.NumericEditField
        EditField_3         matlab.ui.control.NumericEditField
        EditField2          matlab.ui.control.EditField
        EditField5          matlab.ui.control.EditField
        EditField5_2        matlab.ui.control.EditField
        EditField5_3        matlab.ui.control.EditField
        Lamp                matlab.ui.control.Lamp
        Switch              matlab.ui.control.Switch
        EditField4          matlab.ui.control.EditField
        SINEPOTWAVEButton   matlab.ui.control.Button
        EXITButton          matlab.ui.control.Button
        PLOTSINEWAVEButton  matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
           % Close the app
           delete(app.UsingSliderGaugeandSwitchUIFigure);
        end

        % Button pushed function: PLOTSINEWAVEButton
        function PLOTSINEWAVEButtonPushed(app, event)
            % A slider value
            A = app.ASlider.Value;
            iv = app.EditField.Value;
            fv = app.EditField_2.Value;
            ne = app.EditField_3.Value;
            
            x = linspace(iv,fv,ne);
            
            u = A*sin(x);
            
            plot(app.UIAxes, x, u, 'LineWidth',3);
            xlim(app.UIAxes, [0, 7]);
            ylim(app.UIAxes, [-6, 6]);
            xlabel(app.UIAxes,'Time(seconds)','FontSize',12)
            ylabel(app.UIAxes, 'u(t)','FontSize',12)
        end

        % Value changed function: ASlider
        function ASliderValueChanged(app, event)
            % Get the slider value
            A = app.ASlider.Value;
            % Set the gauge value to the same as the slider value
            app.Gauge.Value = A;
            
        end

        % Value changing function: ASlider
        function ASliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
            value = app.Switch.Value;
            app.Lamp.Color = 'black';
            % If switch is on
            if value == "On"
                % Turn on the lamp
                app.Lamp.Color = 'green';
                % Turn on the grid
                grid(app.UIAxes, 'on');
                % If switch is off
            else
                % Turn off the lamp
                app.Lamp.Color = 'black';
                % Turn off the grid
                grid(app.UIAxes, 'off');
            end    
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            iv = app.EditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            fv = app.EditField_2.Value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            ne = app.EditField_3.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UsingSliderGaugeandSwitchUIFigure = uifigure('Visible', 'off');
            app.UsingSliderGaugeandSwitchUIFigure.Name = 'Using Slider Gauge and Switch';
            app.ParametersPanel = uipanel(app.UsingSliderGaugeandSwitchUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.Label = uilabel(app.ParametersPanel);
            app.Label.Text = {''; ''};
            app.Gauge = uigauge(app.ParametersPanel, 'semicircular');
            app.Gauge.Limits = [0 10];
            app.ASliderLabel = uilabel(app.ParametersPanel);
            app.ASliderLabel.Text = 'A :';
            app.ASlider = uislider(app.ParametersPanel);
            app.ASlider.Limits = [0 10];
            app.ASlider.ValueChangedFcn = createCallbackFcn(app, @ASliderValueChanged, true);
            app.ASlider.ValueChangingFcn = createCallbackFcn(app, @ASliderValueChanging, true);
            app.EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.ParametersPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.ParametersPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField2 = uieditfield(app.ParametersPanel, 'text');
            app.EditField2.Value = 'x :';
            app.EditField5 = uieditfield(app.ParametersPanel, 'text');
            app.EditField5.Value = 'iv';
            app.EditField5_2 = uieditfield(app.ParametersPanel, 'text');
            app.EditField5_2.Value = 'fv';
            app.EditField5_3 = uieditfield(app.ParametersPanel, 'text');
            app.EditField5_3.Value = 'ne';
            app.Lamp = uilamp(app.UsingSliderGaugeandSwitchUIFigure);
            app.Switch = uiswitch(app.UsingSliderGaugeandSwitchUIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.EditField4 = uieditfield(app.UsingSliderGaugeandSwitchUIFigure, 'text');
            app.EditField4.Value = 'Grid';
            app.SINEPOTWAVEButton = uibutton(app.UsingSliderGaugeandSwitchUIFigure, 'push');
            app.SINEPOTWAVEButton.Text = 'SINE POT WAVE';
            app.EXITButton = uibutton(app.UsingSliderGaugeandSwitchUIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.PLOTSINEWAVEButton = uibutton(app.UsingSliderGaugeandSwitchUIFigure, 'push');
            app.PLOTSINEWAVEButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTSINEWAVEButtonPushed, true);
            app.PLOTSINEWAVEButton.Text = 'PLOT SINE WAVE';
            app.UIAxes = uiaxes(app.UsingSliderGaugeandSwitchUIFigure);
            title(app.UIAxes, 'f(x) = Asin(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UsingSliderGaugeandSwitchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = homework2_usingslidergaugeswitch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UsingSliderGaugeandSwitchUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UsingSliderGaugeandSwitchUIFigure)
        end
    end
end