classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ExitButton    matlab.ui.control.Button
        sliderresult  matlab.ui.control.NumericEditField
        Slider        matlab.ui.control.Slider
        SliderLabel   matlab.ui.control.Label
        PlotButton    matlab.ui.control.Button
        ButtonGroup   matlab.ui.container.ButtonGroup
        tanButton     matlab.ui.control.RadioButton
        cosButton     matlab.ui.control.RadioButton
        sinButton     matlab.ui.control.RadioButton
        SwitchLabel   matlab.ui.control.Label
        Lamp          matlab.ui.control.Lamp
        LampLabel     matlab.ui.control.Label
        Switch        matlab.ui.control.Switch
        UIAxes        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        value = app.Switch.Value;
       
        if value=='Off'

            app.Lamp.Color=[0.5,0.5,0.5];
           
        end
        
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Off') 
                app.Lamp.Color=[1,0,0];
            else
                app.Lamp.Color=[0,1,0];
            
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.sliderresult.Value=value;

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            t=1:1:100;
          
            if selectedButton==app.sinButton
                f=sin(t);
                plot(app.UIAxes,t,f);
            end
            
        end

        % Value changed function: sliderresult
        function sliderresultValueChanged(app, event)
            value = app.sliderresult.Value;
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.sinButton = uiradiobutton(app.ButtonGroup);
            app.sinButton.Text = 'sin';
            app.sinButton.Value = true;
            app.cosButton = uiradiobutton(app.ButtonGroup);
            app.cosButton.Text = 'cos';
            app.tanButton = uiradiobutton(app.ButtonGroup);
            app.tanButton.Text = 'tan';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.sliderresult = uieditfield(app.UIFigure, 'numeric');
            app.sliderresult.ValueChangedFcn = createCallbackFcn(app, @sliderresultValueChanged, true);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end