classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Table                    matlab.ui.container.Tab
        TypeofparametersPanel    matlab.ui.container.Panel
        DropDown                 matlab.ui.control.DropDown
        Panel                    matlab.ui.container.Panel
        NumberofColumnEditField  matlab.ui.control.NumericEditField
        NumberofColumnLabel      matlab.ui.control.Label
        NumberofRowEditField     matlab.ui.control.NumericEditField
        NumberofRowLabel         matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        ShowinTableButton        matlab.ui.control.Button
        QuadraticFunctionTab     matlab.ui.container.Tab
        SineWaveFuncitonTab      matlab.ui.container.Tab
        Button                   matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowinTableButton
        function ShowinTableButtonPushed(app, event)
            value=app.DropDown.Value;
            nr=app.NumberofRowEditField.Value;
            nc=app.NumberofColumnEditField.Value;
            switch value
                case "Zeros";
                    res =zeros(nr,nc);
                    app.UITable.Data=[res];
                case "Ones";
                    res =ones(nr,nc);
                    app.UITable.Data=[res];
                case "Eye";
                    res =eye(nr,nc);
                    app.UITable.Data=[res];
                case "Rand";
                    res =rand(nr,nc);
                    app.UITable.Data=[res];
                case "Randn";
                    res =randn(nr,nc);
                    app.UITable.Data=[res];
                case "Randi";
                    res =randi(nr,nc);
                    app.UITable.Data=[res];
            end
            
                
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            t=1:2:100;
            f=sin(t);
            plot(app.UIAxes,t,f);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.ShowinTableButton = uibutton(app.Table, 'push');
            app.ShowinTableButton.ButtonPushedFcn = createCallbackFcn(app, @ShowinTableButtonPushed, true);
            app.ShowinTableButton.Text = 'Show in Table';
            app.UITable = uitable(app.Table);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Panel = uipanel(app.Table);
            app.Panel.Title = 'Panel';
            app.NumberofRowLabel = uilabel(app.Panel);
            app.NumberofRowLabel.Text = 'Number of Row :';
            app.NumberofRowEditField = uieditfield(app.Panel, 'numeric');
            app.NumberofColumnLabel = uilabel(app.Panel);
            app.NumberofColumnLabel.Text = 'Number of Column :';
            app.NumberofColumnEditField = uieditfield(app.Panel, 'numeric');
            app.TypeofparametersPanel = uipanel(app.Table);
            app.TypeofparametersPanel.Title = 'Type of parameters';
            app.DropDown = uidropdown(app.TypeofparametersPanel);
            app.DropDown.Items = {'Zeros', 'Ones', 'Eye', 'Rand', 'Randn', 'Randi'};
            app.DropDown.Value = 'Zeros';
            app.QuadraticFunctionTab = uitab(app.TabGroup);
            app.QuadraticFunctionTab.Title = 'Quadratic Function';
            app.SineWaveFuncitonTab = uitab(app.TabGroup);
            app.SineWaveFuncitonTab.Title = 'Sine Wave Funciton';
            app.UIAxes = uiaxes(app.SineWaveFuncitonTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.SineWaveFuncitonTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end